classdef UI_RuptureData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        Label                  matlab.ui.control.Label
        FileLoadDropDown       matlab.ui.control.DropDown
        FileLoadDropDownLabel  matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        extractedData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            files = dir('*.mat');
            numFiles = length(files);
            name = cell(numFiles,1);
            force = cell(numFiles,1);
            
            
            for i=1:numFiles % This loop assigns the file with the forces
                currentFile = files(i).name;
                loadedData = load(currentFile);
                name{i} = currentFile;
                force{i} = loadedData.F;
                
            end
            app.extractedData = table(name, force);
            app.FileLoadDropDown.Items = name;
        end

        % Value changed function: FileLoadDropDown
        function FileLoadDropDownValueChanged(app, event)
            selectedName = app.FileLoadDropDown.Value;

            if ~isempty(selectedName)
                selectedRow = app.extractedData(strcmp(app.extractedData.name, selectedName), :);
                selectedForce = selectedRow.force{1};
                time = 0:length(selectedRow.force{1}) - 1;
    
                plot(app.UIAxes3,time, selectedForce);
                xlabel(app.UIAxes3, 'x-Axis');
                ylabel(app.UIAxes3, 'Force(pN)');
                xlim(app.UIAxes3,[0 max(time)])
                rangeForce = max(selectedForce)-min(selectedForce);
                if rangeForce>1
                    ylim(app.UIAxes3,[min(selectedForce) max(selectedForce)]);
                else
                    ylim(app.UIAxes3,[0 max(selectedForce)*1.1]);
                end
   
    
                posForceData = selectedForce(selectedForce>-5); 
                nForceData = (posForceData - min(posForceData)) / (max(posForceData)- min(posForceData)) * max(selectedForce) ;
                binWidth = .3;
                binEdges = 0:binWidth:max(selectedForce);
    
                bin_counts = histcounts(nForceData,binEdges);
                prob_density = bin_counts / (sum(bin_counts) * binWidth);
                bin_centers = binEdges(1:end-1) + binWidth / 2;
                bar(app.UIAxes2, bin_centers, prob_density, 'FaceColor', 'k');
                xlabel (app.UIAxes2, 'Rupture force(pN)')
                ylabel (app.UIAxes2, 'Estimated probability density (1/pN)')
                xlim(app.UIAxes2,[0 max(selectedForce)]);
                ylim(app.UIAxes2,[0 max(prob_density)*1.1]);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {205, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileLoadDropDownLabel = uilabel(app.LeftPanel);
            app.FileLoadDropDownLabel.Text = 'File Load';
            app.FileLoadDropDown = uidropdown(app.LeftPanel);
            app.FileLoadDropDown.ValueChangedFcn = createCallbackFcn(app, @FileLoadDropDownValueChanged, true);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Histogram')
            xlabel(app.UIAxes2, 'Force (pN)')
            ylabel(app.UIAxes2, 'Probability Density')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Force Data')
            xlabel(app.UIAxes3, 'Time(s)')
            ylabel(app.UIAxes3, 'F')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_RuptureData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end